classdef App_Imagen_Pecas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        DatosPanel        matlab.ui.container.Panel
        compacValue       matlab.ui.control.Label
        compacLabel       matlab.ui.control.Label
        perimValue        matlab.ui.control.Label
        parimLabel        matlab.ui.control.Label
        areaValue         matlab.ui.control.Label
        areaLabel         matlab.ui.control.Label
        ColorpecaButton   matlab.ui.control.Button
        VSelectorPanel    matlab.ui.container.Panel
        MaxSlider_V       matlab.ui.control.Slider
        MaxSlider_3Label  matlab.ui.control.Label
        MinSlider_V       matlab.ui.control.Slider
        MinSlider_3Label  matlab.ui.control.Label
        SSelectorPanel    matlab.ui.container.Panel
        MaxSlider_S       matlab.ui.control.Slider
        MaxSlider_2Label  matlab.ui.control.Label
        MinSlider_S       matlab.ui.control.Slider
        MinSlider_2Label  matlab.ui.control.Label
        HSelectorPanel    matlab.ui.container.Panel
        MaxSlider_H       matlab.ui.control.Slider
        MaxSliderLabel    matlab.ui.control.Label
        MinSlider_H       matlab.ui.control.Slider
        MinSliderLabel    matlab.ui.control.Label
        apply             matlab.ui.control.Button
        open_file         matlab.ui.control.Button
        Label1            matlab.ui.control.Label
        imag_orig         matlab.ui.control.UIAxes
        RightPanel        matlab.ui.container.Panel
        new_window        matlab.ui.control.Button
        saveas            matlab.ui.control.Button
        copy_orig         matlab.ui.control.Button
        Label2            matlab.ui.control.Label
        imag_mask         matlab.ui.control.UIAxes
        imag_processed    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        imag_orig_mat % pixel values of the original image
        imag_proc_mat % pixel values of the processed image
    end
    
    methods (Access = private)
        
        function [BW,maskedRGBImage] = createMask(app, RGB) 
            %createMask  Threshold RGB image using auto-generated code from colorThresholder app. 
            %  [BW,MASKEDRGBIMAGE] = createMask(RGB) thresholds image RGB using 
            %  auto-generated code from the colorThresholder App. The colorspace and 
            %  minimum/maximum values for each channel of the colorspace were set in the 
            %  App and result in a binary mask BW and a composite image maskedRGBImage, 
            %  which shows the original RGB image values under the mask BW.  
            % Auto-generated by colorThresholder app on 22-Jun-2016 
            %------------------------------------------------------  
            % Convert RGB image to chosen color space 
            I = rgb2hsv(RGB);  
            % Define thresholds for channel 1 based on histogram settings 
            channel1Min = app.MinSlider_H.Value; 
            channel1Max = app.MaxSlider_H.Value;  
            % Define thresholds for channel 2 based on histogram settings 
            channel2Min = app.MinSlider_S.Value; 
            channel2Max = app.MaxSlider_S.Value;  
            % Define thresholds for channel 3 based on histogram settings 
            channel3Min = app.MinSlider_V.Value; 
            channel3Max = app.MaxSlider_V.Value;  
            % Create mask based on chosen histogram thresholds 
            sliderBW = (I(:,:,1) >= channel1Min ) & (I(:,:,1) <= channel1Max) & ...     
                (I(:,:,2) >= channel2Min ) & (I(:,:,2) <= channel2Max) & ...     
                (I(:,:,3) >= channel3Min ) & (I(:,:,3) <= channel3Max); 
            BW = sliderBW;  
            % Initialize output masked image based on input image. 
            maskedRGBImage = RGB;  
            % Set background pixels where BW is false to zero. 
            maskedRGBImage(repmat(~BW,[1 1 3])) = 0;  
        end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear min;
            clear index;
            axis(app.imag_orig, 'image');
            axis(app.imag_processed, 'image');            
        end

        % Button pushed function: open_file
        function open_fileButtonPushed(app, event)
            
            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
               errordlg('The image must be colored');
               return
            end
            
            imshow(image,'Parent',app.imag_orig,'InitialMagnification','fit');
            %imagesc(app.imag_orig,image);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: saveas
        function saveasButtonPushed(app, event)

            [filename, pathname]= uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Save image file as ...');
            
            if isequal(filename,0)
               warndlg('File not selected');
               return
            end
            
            image=app.imag_proc_mat;
            
            imwrite(image,strcat(pathname,filename));            
        end

        % Button pushed function: new_window
        function new_windowPushed(app, event)

            image=app.imag_proc_mat;
            
            h=figure;
            imshow(image,'Parent',axes(h));            
        end

        % Button pushed function: copy_orig
        function copy_origButtonPushed(app, event)

            image=app.imag_proc_mat;
            
            imshow(image,'Parent',app.imag_orig);
            
            app.imag_orig_mat=image;            
        end

        % Button pushed function: apply
        function applyButtonPushed(app, event)

            
            image=app.imag_orig_mat;
            fig = figure;
            imshow(image)
            h_rect = getrect();
            close(fig)
            % Rectangle position is given as [xmin, ymin, width, height]
            image_cropped = imcrop(image,h_rect);
            % Seleccionamos el pixel central de la mancha
            fig = figure;
            pixel = impixel(image_cropped);
            close(fig);
            % Lo transforma a HSV
            pixel = pixel./255;
            hsv = rgb2hsv(pixel);
            % Calcula los valores de umbral para la máscara
            if(hsv(1) < .01)
                app.MinSlider_H.Value = hsv(1) - .01;
            end
            app.MaxSlider_H.Value = hsv(1) + .01;

            if(hsv(2) < .05)
                app.MinSlider_S.Value = hsv(2) - .05;
            end
            app.MaxSlider_S.Value = hsv(2) + .05;

            if(hsv(3) < .05)
                app.MinSlider_V.Value = hsv(3) - .05;
            end
            app.MaxSlider_V.Value = hsv(3) + .05;
            % Le sacamos la mascara
            [BW, image_proc] = createMask(app, image_cropped);
            
            % BW2 = bwmorph(BW,'majority',1); % Hace el punto demasiado
            % pequeño
            imshow(image_proc,'Parent',app.imag_processed);
            imshow(BW,'Parent',app.imag_mask);

            % Calcula el area
            area = bwarea(BW);
            app.areaValue.Text = num2str(area,6);
            % Calcula el perimetro
            P = regionprops( BW ,'Perimeter');
            % TODO: Buscar como usar regionprops
            perim = P.Perimeter;
            app.perimValue.Text = num2str(perim,6); 
            % Calcula la compacidad
            C = regionprops(BW, 'Solidity');
            circ = C.Solidity;
            app.compacValue.Text = num2str(circ,6);
        end

        % Button pushed function: ColorpecaButton
        function updateColorPeca(app, event)

            app.MinSlider_H.Value = .05;
            app.MaxSlider_H.Value = .07;

            app.MinSlider_S.Value = .7;
            app.MaxSlider_S.Value = .8;

            app.MinSlider_V.Value = .35;
            app.MaxSlider_V.Value = .45;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {651, 651};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {529, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.imag_orig = uiaxes(app.LeftPanel);
            app.imag_orig.XTick = [];
            app.imag_orig.XTickLabel = {'[ ]'};
            app.imag_orig.YTick = [];
            app.imag_orig.Visible = 'off';
            app.Label1 = uilabel(app.LeftPanel);
            app.Label1.Text = 'ORIGINAL IMAGE';
            app.open_file = uibutton(app.LeftPanel, 'push');
            app.open_file.ButtonPushedFcn = createCallbackFcn(app, @open_fileButtonPushed, true);
            app.open_file.Text = 'Open ...';
            app.apply = uibutton(app.LeftPanel, 'push');
            app.apply.ButtonPushedFcn = createCallbackFcn(app, @applyButtonPushed, true);
            app.apply.Text = 'APPLY';
            app.HSelectorPanel = uipanel(app.LeftPanel);
            app.HSelectorPanel.Title = 'H Selector';
            app.MinSliderLabel = uilabel(app.HSelectorPanel);
            app.MinSliderLabel.Text = 'Min';
            app.MinSlider_H = uislider(app.HSelectorPanel);
            app.MinSlider_H.Limits = [0 1];
            app.MaxSliderLabel = uilabel(app.HSelectorPanel);
            app.MaxSliderLabel.Text = 'Max';
            app.MaxSlider_H = uislider(app.HSelectorPanel);
            app.MaxSlider_H.Limits = [0 1];
            app.MaxSlider_H.Value = 1;
            app.SSelectorPanel = uipanel(app.LeftPanel);
            app.SSelectorPanel.Title = 'S Selector';
            app.MinSlider_2Label = uilabel(app.SSelectorPanel);
            app.MinSlider_2Label.Text = 'Min';
            app.MinSlider_S = uislider(app.SSelectorPanel);
            app.MinSlider_S.Limits = [0 1];
            app.MaxSlider_2Label = uilabel(app.SSelectorPanel);
            app.MaxSlider_2Label.Text = 'Max';
            app.MaxSlider_S = uislider(app.SSelectorPanel);
            app.MaxSlider_S.Limits = [0 1];
            app.MaxSlider_S.Value = 1;
            app.VSelectorPanel = uipanel(app.LeftPanel);
            app.VSelectorPanel.Title = 'V Selector';
            app.MinSlider_3Label = uilabel(app.VSelectorPanel);
            app.MinSlider_3Label.Text = 'Min';
            app.MinSlider_V = uislider(app.VSelectorPanel);
            app.MinSlider_V.Limits = [0 1];
            app.MaxSlider_3Label = uilabel(app.VSelectorPanel);
            app.MaxSlider_3Label.Text = 'Max';
            app.MaxSlider_V = uislider(app.VSelectorPanel);
            app.MaxSlider_V.Limits = [0 1];
            app.MaxSlider_V.Value = 1;
            app.ColorpecaButton = uibutton(app.LeftPanel, 'push');
            app.ColorpecaButton.ButtonPushedFcn = createCallbackFcn(app, @updateColorPeca, true);
            app.ColorpecaButton.Text = 'Color peca';
            app.DatosPanel = uipanel(app.LeftPanel);
            app.DatosPanel.Title = 'Datos';
            app.areaLabel = uilabel(app.DatosPanel);
            app.areaLabel.Text = 'Área: ';
            app.areaValue = uilabel(app.DatosPanel);
            app.areaValue.Text = '0';
            app.parimLabel = uilabel(app.DatosPanel);
            app.parimLabel.Text = 'Perímetro: ';
            app.perimValue = uilabel(app.DatosPanel);
            app.perimValue.Text = '0';
            app.compacLabel = uilabel(app.DatosPanel);
            app.compacLabel.Text = 'Compacidad:';
            app.compacValue = uilabel(app.DatosPanel);
            app.compacValue.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.imag_processed = uiaxes(app.RightPanel);
            app.imag_processed.XTick = [];
            app.imag_processed.XTickLabel = {'[ ]'};
            app.imag_processed.YTick = [];
            app.imag_processed.Visible = 'off';
            app.imag_mask = uiaxes(app.RightPanel);
            app.imag_mask.XTick = [];
            app.imag_mask.XTickLabel = {'[ ]'};
            app.imag_mask.YTick = [];
            app.imag_mask.Visible = 'off';
            app.Label2 = uilabel(app.RightPanel);
            app.Label2.Text = 'PROCESSED IMAGE';
            app.copy_orig = uibutton(app.RightPanel, 'push');
            app.copy_orig.ButtonPushedFcn = createCallbackFcn(app, @copy_origButtonPushed, true);
            app.copy_orig.Text = 'Copy to Original';
            app.saveas = uibutton(app.RightPanel, 'push');
            app.saveas.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveas.Text = 'Save As ...';
            app.new_window = uibutton(app.RightPanel, 'push');
            app.new_window.ButtonPushedFcn = createCallbackFcn(app, @new_windowPushed, true);
            app.new_window.Text = 'New Window';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Imagen_Pecas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end